classdef Controllable_Fgen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        StepTab                         matlab.ui.container.Tab
        AmplitudeVEditFieldLabel        matlab.ui.control.Label
        AmplitudeVEditField             matlab.ui.control.NumericEditField
        DelayEditField_4Label           matlab.ui.control.Label
        DelayEditField_4                matlab.ui.control.NumericEditField
        PulseTab                        matlab.ui.container.Tab
        AmplitudeVEditField_3Label      matlab.ui.control.Label
        AmplitudeVEditField_3           matlab.ui.control.NumericEditField
        DelayEditField_5Label           matlab.ui.control.Label
        DelayEditField_5                matlab.ui.control.NumericEditField
        DurationEditField_4Label        matlab.ui.control.Label
        DurationEditField_4             matlab.ui.control.NumericEditField
        PulseTrainTab                   matlab.ui.container.Tab
        AmplitudeVEditField_2Label      matlab.ui.control.Label
        AmplitudeVEditField_2           matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        FrequencyHzor1cmEditFieldLabel  matlab.ui.control.Label
        FrequencyHzor1cmEditField       matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel        matlab.ui.control.Label
        PulseWidthEditField             matlab.ui.control.NumericEditField
        GaussianTab                     matlab.ui.container.Tab
        CenterEditFieldLabel            matlab.ui.control.Label
        CenterEditField                 matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        DelayEditField_2Label           matlab.ui.control.Label
        DelayEditField_2                matlab.ui.control.NumericEditField
        RampTab                         matlab.ui.container.Tab
        SlopeEditFieldLabel             matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.EditField
        DelayEditField_3Label           matlab.ui.control.Label
        DelayEditField_3                matlab.ui.control.EditField
        DurationEditField_2Label        matlab.ui.control.Label
        DurationEditField_2             matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.NumericEditField
        TriangleTab                     matlab.ui.container.Tab
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        DurationEditField_3Label        matlab.ui.control.Label
        DurationEditField_3             matlab.ui.control.NumericEditField
        PeakEditFieldLabel              matlab.ui.control.Label
        PeakEditField                   matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ParameterLinkingDropDownLabel   matlab.ui.control.Label
        ParameterLinkingDropDown        matlab.ui.control.DropDown
        PulseCheckBox                   matlab.ui.control.CheckBox
        PulseTrainCheckBox              matlab.ui.control.CheckBox
        GaussianCheckBox                matlab.ui.control.CheckBox
        RampCheckBox                    matlab.ui.control.CheckBox
        TriangleCheckBox                matlab.ui.control.CheckBox
        SessionSwitchLabel              matlab.ui.control.Label
        SessionSwitch                   matlab.ui.control.Switch
        StepCheckBox                    matlab.ui.control.CheckBox
        UpdateButton                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pulseAmp =0 % amplitude of pulse V
        pulseDuration = 0; % s
        pulseDelay = 0 % s
        pulseOn = false
        stepAmp =0 % amplitude of pulse V
        stepDelay = 0 % s
        stepOn = false
        s % session variable

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            app.stepAmp = app.AmplitudeVEditField.Value;
            
        end

        % Value changed function: DelayEditField_4
        function DelayEditField_4ValueChanged(app, event)
            app.stepDelay = app.DelayEditField_4.Value;
            
        end

        % Callback function
        function EnabledCheckBox_4ValueChanged(app, event)
            app.stepOn = app.EnabledCheckBox_4.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            if app.stepOn
                pause(app.stepDelay)
                outputScan = app.stepAmp;
                outputSingleScan(app.s,outputScan);
            end
            plot(app.UIAxes,[0:.1:1],ones(1,11)*app.stepAmp)
            ylim([0,5]);
        end

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            switch isStartOn 
                case 'On'
                    app.s = daq.createSession('ni');
                    addAnalogOutputChannel(app.s,'Dev1','ao0','Voltage');
                    disp(isStartOn)
                case 'Off'
                    outputScan = 0;
                    outputSingleScan(app.s,outputScan);
                    clear app.s
            end
        end

        % Value changed function: AmplitudeVEditField_3
        function AmplitudeVEditField_3ValueChanged(app, event)
            app.pulseAmp = app.AmplitudeVEditField_3.Value;
            
        end

        % Value changed function: DurationEditField_4
        function DurationEditField_4ValueChanged(app, event)
            app.pulseDuration = app.DurationEditField_4.Value;
            
        end

        % Value changed function: DelayEditField_5
        function DelayEditField_5ValueChanged(app, event)
            app.pulseOn = app.DelayEditField_5.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.StepTab = uitab(app.TabGroup);
            app.StepTab.Title = 'Step';
            app.AmplitudeVEditFieldLabel = uilabel(app.StepTab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.StepTab, 'numeric');
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.DelayEditField_4Label = uilabel(app.StepTab);
            app.DelayEditField_4Label.Text = 'Delay';
            app.DelayEditField_4 = uieditfield(app.StepTab, 'numeric');
            app.DelayEditField_4.ValueChangedFcn = createCallbackFcn(app, @DelayEditField_4ValueChanged, true);
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.AmplitudeVEditField_3Label = uilabel(app.PulseTab);
            app.AmplitudeVEditField_3Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField_3 = uieditfield(app.PulseTab, 'numeric');
            app.AmplitudeVEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditField_3ValueChanged, true);
            app.DelayEditField_5Label = uilabel(app.PulseTab);
            app.DelayEditField_5Label.Text = 'Delay';
            app.DelayEditField_5 = uieditfield(app.PulseTab, 'numeric');
            app.DelayEditField_5.ValueChangedFcn = createCallbackFcn(app, @DelayEditField_5ValueChanged, true);
            app.DurationEditField_4Label = uilabel(app.PulseTab);
            app.DurationEditField_4Label.Text = 'Duration';
            app.DurationEditField_4 = uieditfield(app.PulseTab, 'numeric');
            app.DurationEditField_4.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_4ValueChanged, true);
            app.PulseTrainTab = uitab(app.TabGroup);
            app.PulseTrainTab.Title = 'Pulse Train';
            app.AmplitudeVEditField_2Label = uilabel(app.PulseTrainTab);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField_2 = uieditfield(app.PulseTrainTab, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.PulseTrainTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.DelayEditFieldLabel = uilabel(app.PulseTrainTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.FrequencyHzor1cmEditFieldLabel = uilabel(app.PulseTrainTab);
            app.FrequencyHzor1cmEditFieldLabel.Text = 'Frequency (Hz or 1/cm)';
            app.FrequencyHzor1cmEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.PulseWidthEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.PulseWidthEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.GaussianTab = uitab(app.TabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.CenterEditFieldLabel = uilabel(app.GaussianTab);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.GaussianTab, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.GaussianTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GaussianTab, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.GaussianTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.GaussianTab, 'numeric');
            app.DelayEditField_2Label = uilabel(app.GaussianTab);
            app.DelayEditField_2Label.Text = 'Delay';
            app.DelayEditField_2 = uieditfield(app.GaussianTab, 'numeric');
            app.RampTab = uitab(app.TabGroup);
            app.RampTab.Title = 'Ramp';
            app.SlopeEditFieldLabel = uilabel(app.RampTab);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RampTab, 'text');
            app.DelayEditField_3Label = uilabel(app.RampTab);
            app.DelayEditField_3Label.Text = 'Delay';
            app.DelayEditField_3 = uieditfield(app.RampTab, 'text');
            app.DurationEditField_2Label = uilabel(app.RampTab);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.RampTab, 'numeric');
            app.AmplitudeEditField_2Label = uilabel(app.RampTab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.RampTab, 'numeric');
            app.TriangleTab = uitab(app.TabGroup);
            app.TriangleTab.Title = 'Triangle';
            app.AmplitudeEditField_3Label = uilabel(app.TriangleTab);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.DurationEditField_3Label = uilabel(app.TriangleTab);
            app.DurationEditField_3Label.Text = 'Duration';
            app.DurationEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.PeakEditFieldLabel = uilabel(app.TriangleTab);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.TriangleTab, 'numeric');
            app.EditFieldLabel = uilabel(app.TriangleTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.TriangleTab, 'numeric');
            app.ParameterLinkingDropDownLabel = uilabel(app.LeftPanel);
            app.ParameterLinkingDropDownLabel.Text = 'Parameter Linking';
            app.ParameterLinkingDropDown = uidropdown(app.LeftPanel);
            app.ParameterLinkingDropDown.Items = {'Distance (cm)', 'Time (s)', 'Velocity (cm/s)'};
            app.ParameterLinkingDropDown.Value = 'Distance (cm)';
            app.PulseCheckBox = uicheckbox(app.LeftPanel);
            app.PulseCheckBox.Text = 'Pulse';
            app.PulseTrainCheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrainCheckBox.Text = 'Pulse Train';
            app.GaussianCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.RampCheckBox = uicheckbox(app.LeftPanel);
            app.RampCheckBox.Text = 'Ramp';
            app.TriangleCheckBox = uicheckbox(app.LeftPanel);
            app.TriangleCheckBox.Text = 'Triangle';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.StepCheckBox = uicheckbox(app.LeftPanel);
            app.StepCheckBox.Text = 'Step';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stimulus Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Amplitude (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controllable_Fgen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end